classdef DeepSqueakCallCategoryRenameTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image4                matlab.ui.control.Image
        SavecallfileButton    matlab.ui.control.Button
        RemovecategoryButton  matlab.ui.control.Button
        ConfirmrenameButton   matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        LoadcallfileButton    matlab.ui.control.Button
        Image3                matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.set('WindowStyle','modal'); % Keeps the window on top when loading a file. 
        end

        % Button pushed function: LoadcallfileButton
        function LoadcallfileButtonPushed(app, event)
            % Pop-up window for user to select call file
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('Cancelled');
            else
               disp(['Selected ', fullfile(path,file)]);
            end
            
            save('file.mat','file'); %Saves the loaded file name so other functions can access it.
        
            fullFile = append(path,file); % Combines the path and file name into 1 string.
            CallFile = load(fullFile); % Loads the selected file.
            cd(path); % Changes current directory to the directory of the call file. 
            save('CallFile.mat','-struct', 'CallFile'); % Saves the call file as a .mat file so that other functions may access it. 
            
            callCats = categories(CallFile.Calls.Type); % Extracts all the call type factegories into variable 'CallCats'
            app.UITable.Data = callCats; % Adds 'CallCats' to the UI table.
           

        end

        % Button pushed function: ConfirmrenameButton
        function ConfirmrenameButtonPushed(app, event)
            newData = load('newData.mat'); % Loads the edited text data created in function UITableCellEdit
            newText = [newData]; % Changes the edited text data from char to struct.
            save('newText.mat','newText'); % Saves the struct of edited data so other functions can acess.
            
            % Same as above, but for the text that was originally in the box.
            oldData = load('oldData.mat'); 
            oldText = [oldData];
            save('oldText.mat','oldText');
            
            % Extracts the values of 'oldText' and 'newText' then shows them in the command window
            oldValue = getfield(oldText, 'oldData');
            disp(oldValue);
            newValue = getfield(newText, 'newData');
            disp(newValue);
            
            % Loads the currently opened call file and renames the category that was changed.
            NewCallFile = load('CallFile.mat');           
            r = renamecats(NewCallFile.Calls.Type,oldValue,newValue);
            NewCallFile.Calls.Type = r;
            
            disp(categories(NewCallFile.Calls.Type)); % Displays the new categories in the command window
            
            save('CallFile.mat','-struct','NewCallFile'); % Saves the updated call file so other functions (including this one) can access it. 
           
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indicesEdit = event.Indices;
            newData = event.NewData;
            app.UITable.Data(indicesEdit(1)) = [cellstr(newData)]; % Updates the selected cell with changes made by user. 
            save('newData.mat', 'newData'); % Saves the new cell text so other functions can access it. 
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indicesSelect = event.Indices;         
            oldData = app.UITable.Data(indicesSelect(1));
            save('oldData.mat', 'oldData');
            disp(oldData);
        end

        % Button pushed function: RemovecategoryButton
        function RemovecategoryButtonPushed(app, event)
            % Removes the old data selected by clicking on the category cell.
            oldData = load('oldData.mat');
            oldText = [oldData];
            save('oldText.mat','oldText');
            
            oldValue = getfield(oldText, 'oldData');
            disp(oldValue);
            
            NewCallFile = load('CallFile.mat');       
            rm = removecats(NewCallFile.Calls.Type, oldValue);
            NewCallFile.Calls.Type = rm;
            
            disp(categories(NewCallFile.Calls.Type)); % Displays updated table of categories in the command window. 
            app.UITable.Data = categories(NewCallFile.Calls.Type); % Updates the table to reflect removed category.
            save('CallFile.mat','-struct','NewCallFile');
            
  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Checks whether files exist. 
            % If yes, deletes the unnecessary files created by other functions. 
            % If no, just closes app. 
            
            % Saves the updated call file before closing the app, in case user forgot to save. 
            if isfile('CallFile.mat') 
                finalFile = load("CallFile.mat");
            else
                delete(app);
            end
            
            if isfile('file.mat')
                file = load('file.mat');
                fileName = getfield(file,'file');
            else
                delete(app);
            end
            
            if isfile('file.mat')
            fileNameNew = append(fileName, '_NEW.mat');
            save(fileNameNew, '-struct', 'finalFile');
            else
                delete(app);
            end
                     
            % Deletes all the unnecessary temp files created in other functions. 
            if isfile("oldText.mat")
                delete('oldText.mat');
            else
                delete(app);
            end
            
            if isfile("newText.mat")
                delete('newText.mat');
            else
                delete(app);
            end
                   
            if isfile("newData.mat")
                delete('newData.mat');
            else
                delete(app);
            end
                          
            if isfile("oldData.mat")
                delete('oldData.mat');
            else
                delete(app);
            end
                              
            if isfile("CallFile.mat")
                delete('CallFile.mat');
            else
                delete(app);
            end
                                        
            if isfile("file.mat")
                delete('file.mat');
            else
                delete(app);
            end
           
            delete(app);
        end

        % Button pushed function: SavecallfileButton
        function SavecallfileButtonPushed(app, event)
            FinalFile = load("CallFile.mat");
            file = load('file.mat'); % Loads file name.
            fileName = getfield(file,'file'); % Extracts only the file name from the 'file' struct.
            [path, name] = fileparts(fileName); % Separates file name into name & extension.
            fileName = name; % Removes extension from file name. 
            
            
            fileNameNew = append(fileName, '_NEW.mat');
            save(fileNameNew, '-struct', 'FinalFile');
            
            saveMessage = append('New call detection file has been saved with the name ', fileNameNew);
            disp(saveMessage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'LabRaPeeking_NoBackground3.png';
            app.LoadcallfileButton = uibutton(app.UIFigure, 'push');
            app.LoadcallfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadcallfileButtonPushed, true);
            app.LoadcallfileButton.Text = 'Load call file';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Call categories'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ConfirmrenameButton = uibutton(app.UIFigure, 'push');
            app.ConfirmrenameButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmrenameButtonPushed, true);
            app.ConfirmrenameButton.Text = 'Confirm rename';
            app.RemovecategoryButton = uibutton(app.UIFigure, 'push');
            app.RemovecategoryButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecategoryButtonPushed, true);
            app.RemovecategoryButton.Text = 'Remove category';
            app.SavecallfileButton = uibutton(app.UIFigure, 'push');
            app.SavecallfileButton.ButtonPushedFcn = createCallbackFcn(app, @SavecallfileButtonPushed, true);
            app.SavecallfileButton.Text = 'Save call file';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = 'LabRatPaw3.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeepSqueakCallCategoryRenameTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end